classdef CTScanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PhantomGenerationTab            matlab.ui.container.Tab
        StructureColorEditField         matlab.ui.control.NumericEditField
        StructureColorEditFieldLabel    matlab.ui.control.Label
        StructureSizeEditField          matlab.ui.control.NumericEditField
        StructureSizeEditFieldLabel     matlab.ui.control.Label
        ShowphatomButton                matlab.ui.control.Button
        MatrixSizeEditField             matlab.ui.control.NumericEditField
        PhantomStructureButtonGroup     matlab.ui.container.ButtonGroup
        HeadPhantomButton               matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        CircularButton                  matlab.ui.control.RadioButton
        MatrixSizeEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ScannerControlsTab              matlab.ui.container.Tab
        RotationStepAngleSlider         matlab.ui.control.Slider
        RotationStepAngleSliderLabel    matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        SourceDistanceDropDown          matlab.ui.control.DropDown
        DetectorArrayTypeDropDownLabel  matlab.ui.control.Label
        DetectorArrayTypeDropDown       matlab.ui.control.DropDown
        SourceDistanceDropDownLabel     matlab.ui.control.Label
        DetectorDensityDropDown         matlab.ui.control.DropDown
        DetectorDensityDropDownLabel    matlab.ui.control.Label
        NumberofDetectorDropDown        matlab.ui.control.DropDown
        NumberofDetectorDropDownLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ImageAnalysisTab                matlab.ui.container.Tab
        TypeofImageAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        SIProfilesButton                matlab.ui.control.RadioButton
        ImageDifferenceButton           matlab.ui.control.RadioButton
        SIandContrastButton             matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MatrixSize
        selectedButton 
        generatedPhantom
        NumberofDetector
        arrTypeDropDown
        selectedDensity
        sourceDest
        rotationSlider
        %num_detectors
        %detector_density
        detector_array_type
        %source_distance
        %rotation_step_angle
    end
    
    methods (Access = private)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%% GENERATR PHANTOM %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function generate_phantom(app)
            matrixsize = app.MatrixSizeEditField.Value;
            sizefactor = app.StructureSizeEditField.Value;
            colorfactor = app.StructureColorEditField.Value;
            % Generate the phantom based on the selected structure and matrix size
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    app.generatedPhantom = zeros(matrixsize);

                    % Define the properties of the circle
                    centerX = matrixsize / 2;
                    centerY = matrixsize / 2;
                    radius = matrixsize / 2.2;
        
                    % Draw outer circle (the body)
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 0.4;
                            end
                        end
                    end

                    % Draw inside circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.5)^2 + (y - matrixsize*0.5)^2 <= (matrixsize*0.1*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                    % Draw left inside circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.55)^2 + (y - matrixsize*0.2)^2 <= (matrixsize*0.05*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                    % Draw the bottom circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.75)^2 + (y - matrixsize*0.5)^2 <= (matrixsize*0.08*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                    % Draw the top baby circle
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - matrixsize*0.25)^2 + (y - matrixsize*0.45)^2 <= (matrixsize*0.03*sizefactor)^2
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end


                case 'Rectangular'
                    % Create a blank image
                    app.generatedPhantom = zeros(matrixsize);

                    centerX = matrixsize / 2;
                    centerY = matrixsize / 2;
                    radius = matrixsize / 2.2;

                    % Draw outer circle (the body)
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 0.4;
                            end
                        end
                    end

                    width = matrixsize*0.2*sizefactor;
                    height = matrixsize*0.4*sizefactor;

                    % Define the properties of the rectangle
                    left = (matrixsize/2-width/2);
                    right = (matrixsize/2+width/2);
                    top = (matrixsize/2-height/2);
                    bottom = (matrixsize/2+height/2);
        
                    % Draw the rectangle in the image
                    for x = 1:matrixsize
                        for y = 1:matrixsize
                            if x >= left && x <= right && y >= top && y <= bottom
                                app.generatedPhantom(x, y) = colorfactor;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    app.generatedPhantom = phantom(matrixsize);
            end
        
            % Display the phantom in the UIAxes
            imshow(app.generatedPhantom, 'Parent', app.UIAxes);

        end 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%% OPERATE SCANNER %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        % New method to operate the scanner
        function scanner_data = operate_scanner(app, phantom, num_detectors, detector_density, detector_array_type, source_distance, rotation_step_angle)
        % Initialize scanner_data structure
        disp('Reached operate_scanner - Start');
    
        scanner_data = struct();
    
        % Get the size of the phantom
        rows = app.MatrixSize;
        cols = app.MatrixSize;
    
        % Extract the selected detector array type from the cell array
        selected_detector_array_type = detector_array_type;
    
        % Initialize detector positions based on the array type
        switch selected_detector_array_type
            case 'Linear'
                detector_positions = linspace(1, cols, num_detectors);
            case 'Arc'
                detector_positions = linspace(1, cols, num_detectors);
                % Assuming a circular arc, you may need to adjust this for your specific case
                detector_positions = detector_positions + sin(linspace(0, pi, num_detectors)) * cols / 4;
        end
    
        rotation_step_angle = max(min(rotation_step_angle, 360), 1);
        rotation_angles = 1:rotation_step_angle:360;
    
        scanner_readings = zeros(num_detectors, numel(rotation_angles));
    
        % Initialize scanner readings
        %scanner_readings = zeros(num_detectors, numel(1:rotation_step_angle:360));
        disp('Reached operate_scanner - Middle');
        % Perform scanning by rotating the source around the phantom
        for angleIdx = 1:length(rotation_angles)
            angle = rotation_angles(angleIdx);
            rotated_phantom = imrotate(phantom, angle, 'crop');
    
            % Calculate source position based on the rotation angle
            source_x = round(rows / 2 + source_distance * cosd(angle));
            source_y = round(cols / 2 + source_distance * sind(angle));
    
            % Collect data from each detector
            for i = 1:num_detectors-1
                detector_x = round(detector_positions(i));
                detector_y = 1;  % You may need to adjust the vertical position based on your setup
    
                % Simulate projection from source to detector
                projection_line = bresenham_line(app, source_x, source_y, detector_x, detector_y);
    
                % Accumulate readings along the projection line with detector density
                for j = 1:length(projection_line)
                    idx = projection_line(j, 1);
                    idy = projection_line(j, 2);
                    if idx > 0 && idy > 0 && idx <= size(rotated_phantom, 1) && idy <= size(rotated_phantom, 2)
                        scanner_readings(i+1, angleIdx) = scanner_readings(i+1, angleIdx) + rotated_phantom(idx, idy) * detector_density;
                    end
                end
            end
        end
    
        % Store the scanner readings in the structure
        scanner_data.readings = scanner_readings;
        scanner_data.detector_positions = detector_positions;
        scanner_data.rotation_angles = 1:rotation_step_angle:360;
    
        disp('Reached operate_scanner - End');
    end

        % Helper function: Bresenham's line algorithm
        function line_points = bresenham_line(app, point1x, point1y, point2x, point2y)
            x1 = point1x;
            y1 = point1y;
            x2 = point2x;
            y2 = point2y;
        
            dx = abs(x2 - x1);
            dy = abs(y2 - y1);
        
            steep = dy > dx;
        
            if steep
                [x1, y1] = app.swap(x1, y1);
                [x2, y2] = app.swap(x2, y2);
            end
        
            if x1 > x2
                [x1, x2] = app.swap(x1, x2);
                [y1, y2] = app.swap(y1, y2);
            end
        
            dx = x2 - x1;
            dy = abs(y2 - y1);
            error = -dx / 2;
            ystep = (y1 < y2) * 2 - 1;
        
            % Initialize line_points array
            line_points = zeros(dx + 1, 2); % Assuming dx is the largest difference
            y = y1;
        
            for x = x1:x2
                if steep
                    line_points(x - x1 + 1, :) = [y, x];
                else
                    line_points(x - x1 + 1, :) = [x, y];
                end
        
                error = error + dy;
        
                if error > 0
                    y = y + ystep;
                    error = error - dx;
                end
            end
        end

        % Helper function: Swap values
        function [a, b] = swap(~, a, b)
            temp = a;
            a = b;
            b = temp;
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%% RECONSTRUCT IMAGE %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         % In reconstruct_image method:
        function reconstructed_image = reconstruct_image(app, scanner_data)
            disp('Reached reconstruct_image - Start');
            % Initialize the reconstructed image
            reconstructed_image = zeros(app.MatrixSize, app.MatrixSize);
            
            % Iterate over detectors and angles to accumulate readings
            for i = 1:length(scanner_data.detector_positions)
                for j = 1:length(scanner_data.rotation_angles)
                    % Calculate the position in the image based on the detector and angle
                    angle = scanner_data.rotation_angles(j);
        
                    % Calculate source position for each rotation angle
                    source_x = round(app.MatrixSize / 2 + app.sourceDest * cosd(angle));
                    source_y = round(app.MatrixSize / 2 + app.sourceDest * sind(angle));
        
                    detector_x = round(scanner_data.detector_positions(i));
                    detector_y = 1;  % Adjust this based on your setup
        
                    % Simulate projection from source to detector
                    projection_line = bresenham_line(app, source_x, source_y, detector_x, detector_y);
        
                    % Accumulate readings along the projection line
                    for line_point = projection_line'
                        reconstructed_image(line_point(1), line_point(2)) = reconstructed_image(line_point(1), line_point(2)) + scanner_data.readings(i, j);
                    end
                end
            end
            disp('Reached reconstruct_image - End');
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%% SI and CONTRAST ANALYSIS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % SI and Contrast Analysis
        function calculateContrast(app)
            % Ensure the phantom is generated
            if isempty(app.generatedPhantom)
                disp('Phantom not generated yet.');
                return;
            end
        
            % Get the size of the phantom
            [rows, cols] = size(app.generatedPhantom);
        
            % Generate two random points within the phantom
            point1 = [randi(rows), randi(cols)];
            point2 = [randi(rows), randi(cols)];
        
            % Extract the intensity values at these points
            intensity1 = app.generatedPhantom(point1(1), point1(2));
            intensity2 = app.generatedPhantom(point2(1), point2(2));
        
            % Calculate and display contrast
            contrast = abs(intensity1 - intensity2);
            disp(['Contrast between points: ', num2str(contrast)]);
            disp(['Point 1 (', num2str(point1(1)), ', ', num2str(point1(2)), '): Intensity = ', num2str(intensity1)]);
            disp(['Point 2 (', num2str(point2(1)), ', ', num2str(point2(2)), '): Intensity = ', num2str(intensity2)]);
           
            % Displaying the results in UIAxes3 or another UI component
            cla(app.UIAxes3);  % Clear existing content
            text(app.UIAxes3, 0.5, 0.5, sprintf('Contrast: %f\nPoint 1 Intensity: %f\nPoint 2 Intensity: %f', contrast, intensity1, intensity2), 'HorizontalAlignment', 'center');
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%% IMAGE DIFFERENCE ANALYSIS %%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        % Image Difference Analysis
        function imageDifferenceAnalysis(app)
            % Placeholder for the reconstructed image (Modify this as needed)
            reconstructed_image = imrotate(app.generatedPhantom, 10, 'bilinear', 'crop'); % Example modification
        
            % Calculate the difference
            difference = abs(app.generatedPhantom - reconstructed_image);
        
            % Display the difference image in UIAxes3
            imshow(difference, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Difference Image');
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%% SINGAL INTENSITY PROFILE %%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%        
        function signalIntensityProfile(app)
            % Placeholder for the reconstructed image (Modify this as needed)
            reconstructed_image = imrotate(app.generatedPhantom, 10, 'bilinear', 'crop'); % Example modification
        
            % Prompt the user to choose which phantom to analyze
            choice = uiconfirm(app.UIFigure, 'Which phantom would you like to analyze?', 'Select Phantom', ...
                               'Options', {'Original', 'Reconstructed'}, ...
                               'DefaultOption', 1, 'CancelOption', 2);
        
            % Determine which phantom to use based on user choice
            if strcmp(choice, 'Original')
                selectedPhantom = app.generatedPhantom;
            elseif strcmp(choice, 'Reconstructed')
                selectedPhantom = reconstructed_image;
            else
                return; % User cancelled or closed the dialog
            end
        
            % Show the selected phantom and let the user draw a line
            figure, imshow(selectedPhantom);
            title('Draw a line for signal intensity profile');
        
            % User draws a line
            h = drawline();
        
            % Get line coordinates
            coords = round(h.Position);
        
            % Extract intensity values along the line
            intensityProfile = improfile(selectedPhantom, coords(:,1), coords(:,2));
        
            % Plot the intensity profile in UIAxes3
            plot(app.UIAxes3, intensityProfile);
            title(app.UIAxes3, 'Signal Intensity Profile');
            xlabel(app.UIAxes3, 'Position');
            ylabel(app.UIAxes3, 'Intensity');
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Set default values for MatrixSize and selectedButton
            app.MatrixSize = 700;  % Set your desired default matrix size
            app.selectedButton = 'Circular';  % Set your desired default phantom structure

        % Update the UI elements with the default values
            app.MatrixSizeEditField.Value = app.MatrixSize;
            app.PhantomStructureButtonGroup.SelectedObject = app.CircularButton;

        % Call the function to generate and display the default phantom
            app.ShowphatomButtonPushed();
        % Set default selection for TypeofImageAnalysisButtonGroup
            app.TypeofImageAnalysisButtonGroup.SelectedObject = app.ImageDifferenceButton;
            app.TypeofImageAnalysisButtonGroupSelectionChanged();

        end

        % Value changed function: MatrixSizeEditField
        function MatrixSizeEditFieldValueChanged(app, event)
            %generate_phantom(app)
        end

        % Value changed function: StructureSizeEditField
        function StructureSizeEditFieldValueChanged(app, event)
            %generate_phantom(app)
        end

        % Value changed function: StructureColorEditField
        function StructureColorEditFieldValueChanged(app, event)
            %generate_phantom(app)
        end

        % Button pushed function: ShowphatomButton
        function ShowphatomButtonPushed(app, event)
            generate_phantom(app)
        end

        % Selection changed function: PhantomStructureButtonGroup
        function PhantomStructureButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.PhantomStructureButtonGroup.SelectedObject.Text;
            %generate_phantom(app)
        end

        % Value changed function: NumberofDetectorDropDown
        function NumberofDetectorDropDownValueChanged(app, event)
            %value = app.NumberofDetectorDropDown.Value;
            app.NumberofDetector = str2double(app.NumberofDetectorDropDown.Value);
            % Use the new value to update your program
            %app.NumberofDetector = str2double(app.num_detectors);
            %disp(['Selected Num of Detector: ' num2str(app.NumberofDetector)]);

        end

        % Value changed function: DetectorDensityDropDown
        function DetectorDensityDropDownValueChanged(app, event)
            %value = app.DetectorDensityDropDown.Value;
            app.selectedDensity = str2double(app.DetectorDensityDropDown.Value);
            %app.detector_density = str2double(app.selectedDensity);
            %disp(['Selected Detector Dest: ' num2str(app.selectedDensity)]);

        end

        % Value changed function: SourceDistanceDropDown
        function SourceDistanceDropDownValueChanged(app, event)
            app.sourceDest = str2double(app.SourceDistanceDropDown.Value);
            %app.source_distance = str2double(app.sourceDest);
            %disp(['Selected Source Dist: ' num2str(app.sourceDest)]);

        end

        % Value changed function: RotationStepAngleSlider
        function RotationStepAngleSliderValueChanged(app, event)
            app.rotationSlider = app.RotationStepAngleSlider.Value;
            %app.rotationSlider = str2double(app.rotationSlider);
            %disp(['Selected Rotation Step Angle: ' num2str(app.rotationSlider)]);

        end

        % Value changed function: DetectorArrayTypeDropDown
        function DetectorArrayTypeDropDownValueChanged(app, event)
            app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value;

            % Use the new value to update your program
            app.detector_array_type = app.arrTypeDropDown;
            %disp(['Selected Arr Type: ' (app.arrTypeDropDown)]);

        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value == 1 % If the Run button is pressed
                % Retrieve and validate the parameters
                app.NumberofDetector = str2double(app.NumberofDetectorDropDown.Value);
                app.selectedDensity = str2double(app.DetectorDensityDropDown.Value);
                app.sourceDest = str2double(app.SourceDistanceDropDown.Value);
                app.rotationSlider = app.RotationStepAngleSlider.Value; % Already a numeric value
                app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value; % String value
        
                % Check for valid number for Number of Detector, Source Distance, and Detector Density
                if isnan(app.NumberofDetector) || isnan(app.selectedDensity) || isnan(app.sourceDest)
                    disp('Invalid input for number of detectors, source distance, or detector density. Please select valid numbers.');
                    return;
                end
        
                % Display the selected parameters
                disp(['Selected Num of Detector: ' num2str(app.NumberofDetector)]);
                disp(['Selected Detector Density: ' num2str(app.selectedDensity)]);
                disp(['Selected Source Distance: ' num2str(app.sourceDest)]);
                disp(['Selected Rotation Step Angle: ' num2str(app.rotationSlider)]);
                disp(['Selected Detector Array Type: ' app.arrTypeDropDown]);
        
                % Generate the phantom
                phantom = app.generate_phantom(); % Assuming you have a method to generate the phantom
        
                % Operate the scanner
                % This will use the retrieved values for scanning
                scanner_data = app.operate_scanner(phantom, app.NumberofDetector, app.selectedDensity, app.arrTypeDropDown, app.sourceDest, app.rotationSlider);
        
                % Reconstruct the image
                reconstructed_image = app.reconstruct_image(scanner_data);
        
                % Display the reconstructed image
                imshow(reconstructed_image, 'Parent', app.UIAxes2);
            end
        end

        % Selection changed function: TypeofImageAnalysisButtonGroup
        function TypeofImageAnalysisButtonGroupSelectionChanged(app, event)
            selectedAnalysis = app.TypeofImageAnalysisButtonGroup.SelectedObject.Text;  % Corrected this line

            switch selectedAnalysis
                case 'SI and Contrast'
                    calculateContrast(app);
                case 'Image Difference'
                    imageDifferenceAnalysis(app);
                case 'SI Profiles'
                    signalIntensityProfile(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhantomGenerationTab = uitab(app.TabGroup);
            app.PhantomGenerationTab.Title = 'Phantom Generation';
            app.UIAxes = uiaxes(app.PhantomGenerationTab);
            title(app.UIAxes, 'Phantom')
            app.MatrixSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.MatrixSizeEditFieldLabel.Text = 'Matrix Size';
            app.PhantomStructureButtonGroup = uibuttongroup(app.PhantomGenerationTab);
            app.PhantomStructureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhantomStructureButtonGroupSelectionChanged, true);
            app.PhantomStructureButtonGroup.Title = 'Phantom Structure';
            app.CircularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.HeadPhantomButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.HeadPhantomButton.Text = 'Head Phantom';
            app.MatrixSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.MatrixSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixSizeEditFieldValueChanged, true);
            app.ShowphatomButton = uibutton(app.PhantomGenerationTab, 'push');
            app.ShowphatomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowphatomButtonPushed, true);
            app.ShowphatomButton.Text = 'Show phatom';
            app.StructureSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.StructureSizeEditFieldLabel.Text = 'Structure Size';
            app.StructureSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.StructureSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StructureSizeEditFieldValueChanged, true);
            app.StructureSizeEditField.Value = 1;
            app.StructureColorEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.StructureColorEditFieldLabel.Text = 'Structure Color';
            app.StructureColorEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.StructureColorEditField.ValueChangedFcn = createCallbackFcn(app, @StructureColorEditFieldValueChanged, true);
            app.StructureColorEditField.Value = 0.7;
            app.ScannerControlsTab = uitab(app.TabGroup);
            app.ScannerControlsTab.Title = 'Scanner Controls';
            app.UIAxes2 = uiaxes(app.ScannerControlsTab);
            title(app.UIAxes2, ' Reconstructed Image')
            app.NumberofDetectorDropDownLabel = uilabel(app.ScannerControlsTab);
            app.NumberofDetectorDropDownLabel.Text = 'Number of Detector';
            app.NumberofDetectorDropDown = uidropdown(app.ScannerControlsTab);
            app.NumberofDetectorDropDown.Items = {'100', '200', '300'};
            app.NumberofDetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofDetectorDropDownValueChanged, true);
            app.NumberofDetectorDropDown.Value = '100';
            app.DetectorDensityDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorDensityDropDownLabel.Text = 'Detector Density';
            app.DetectorDensityDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorDensityDropDown.Items = {'1', '5', '20'};
            app.DetectorDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDensityDropDownValueChanged, true);
            app.DetectorDensityDropDown.Value = '1';
            app.SourceDistanceDropDownLabel = uilabel(app.ScannerControlsTab);
            app.SourceDistanceDropDownLabel.Text = 'Source Distance';
            app.DetectorArrayTypeDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDown.Items = {'Linear', 'Arc'};
            app.DetectorArrayTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorArrayTypeDropDownValueChanged, true);
            app.DetectorArrayTypeDropDown.Value = 'Linear';
            app.DetectorArrayTypeDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDownLabel.Text = 'Detector Array Type';
            app.SourceDistanceDropDown = uidropdown(app.ScannerControlsTab);
            app.SourceDistanceDropDown.Items = {'100', '200', '300'};
            app.SourceDistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDistanceDropDownValueChanged, true);
            app.SourceDistanceDropDown.Value = '100';
            app.RunButton = uibutton(app.ScannerControlsTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RotationStepAngleSliderLabel = uilabel(app.ScannerControlsTab);
            app.RotationStepAngleSliderLabel.Text = 'Rotation Step Angle';
            app.RotationStepAngleSlider = uislider(app.ScannerControlsTab);
            app.RotationStepAngleSlider.Limits = [0 360];
            app.RotationStepAngleSlider.ValueChangedFcn = createCallbackFcn(app, @RotationStepAngleSliderValueChanged, true);
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.UIAxes3 = uiaxes(app.ImageAnalysisTab);
            title(app.UIAxes3, 'Image Analysis')
            app.TypeofImageAnalysisButtonGroup = uibuttongroup(app.ImageAnalysisTab);
            app.TypeofImageAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofImageAnalysisButtonGroupSelectionChanged, true);
            app.TypeofImageAnalysisButtonGroup.Title = 'Type of Image Analysis';
            app.SIandContrastButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIandContrastButton.Text = 'SI and Contrast';
            app.SIandContrastButton.Value = true;
            app.ImageDifferenceButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.ImageDifferenceButton.Text = 'Image Difference';
            app.SIProfilesButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIProfilesButton.Text = 'SI Profiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CTScanner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end