classdef ImageReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PhantomGenerationTab            matlab.ui.container.Tab
        ShowphatomButton                matlab.ui.control.Button
        MatrixSizeEditField             matlab.ui.control.NumericEditField
        PhantomStructureButtonGroup     matlab.ui.container.ButtonGroup
        HeadPhantomButton               matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        CircularButton                  matlab.ui.control.RadioButton
        MatrixSizeEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ScannerControlsTab              matlab.ui.container.Tab
        RotationStepAngleSlider         matlab.ui.control.Slider
        RotationStepAngleSliderLabel    matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        SourceDistanceDropDown          matlab.ui.control.DropDown
        DetectorArrayTypeDropDown       matlab.ui.control.DropDown
        DetectorArrayTypeDropDownLabel  matlab.ui.control.Label
        SourceDistanceDropDownLabel     matlab.ui.control.Label
        DetectorDensityDropDown         matlab.ui.control.DropDown
        DetectorDensityDropDownLabel    matlab.ui.control.Label
        NumberofDetectorDropDown        matlab.ui.control.DropDown
        NumberofDetectorDropDownLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ImageAnalysisTab                matlab.ui.container.Tab
        TypeofImageAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        SIProfilesButton                matlab.ui.control.RadioButton
        ImageDifferenceButton           matlab.ui.control.RadioButton
        SIandContrastButton             matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MatrixSize
        selectedButton 
        generatedPhantom
        NumberofDetector
        arrTypeDropDown
        selectedDensity
        sourceDest
        rotationSlider
        num_detectors
        detector_density
        detector_array_type
        %source_distance
        rotation_step_angle
    end
    
    methods (Access = private)
        
        function phantom_img = generate_phantom(app)
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    phantom_img = zeros(app.MatrixSize);

                    % Define the properties of the circle
                    centerX = app.MatrixSize / 2;
                    centerY = app.MatrixSize / 2;
                    radius = app.MatrixSize / 4;

                    % Draw the circle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                phantom_img(x, y) = 1;
                            end
                        end
                    end

                case 'Rectangular'
                    % Create a blank image
                    phantom_img = zeros(app.MatrixSize);

                    % Define the properties of the rectangle
                    left = app.MatrixSize / 4;
                    right = 3 * app.MatrixSize / 4;
                    top = app.MatrixSize / 4;
                    bottom = 3 * app.MatrixSize / 4;

                    % Draw the rectangle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if x >= left && x <= right && y >= top && y <= bottom
                                phantom_img(x, y) = 1;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    phantom_img = phantom(app.MatrixSize);
            end
        end 
        
        % New method to operate the scanner
        function scanner_data = operate_scanner(app, phantom, num_detectors, detector_density, detector_array_type, source_distance, rotation_step_angle)
            % Initialize scanner_data structure
            scanner_data = struct();
        
            % Get the size of the phantom
            %[rows, cols] = size(phantom);
            rows = app.MatrixSize
            cols = app.MatrixSize
        
            % Extract the selected detector array type from the cell array
            selected_detector_array_type = detector_array_type{1};
        
            % Initialize detector positions based on the array type
            switch selected_detector_array_type
                case 'Linear'
                    detector_positions = linspace(1, cols, num_detectors);
                case 'Arc'
                    detector_positions = linspace(1, cols, num_detectors);
                    % Assuming a circular arc, you may need to adjust this for your specific case
                    detector_positions = detector_positions + sin(linspace(0, pi, num_detectors)) * cols / 4;
            end
        

            rotation_step_angle = max(min(rotation_step_angle, 360), 1);
            rotation_angles = 1:rotation_step_angle:360;

            scanner_readings = zeros(num_detectors, numel(rotation_angles));
            % Initialize scanner readings
            %scanner_readings = zeros(num_detectors, numel(1:rotation_step_angle:360));
        
            % Perform scanning by rotating the source around the phantom
            for angle = 1:rotation_step_angle:360
                % Rotate the phantom
                rotated_phantom = imrotate(phantom, angle, 'crop');
        
                % Calculate source position based on the rotation angle
                source_x = round(rows / 2 + source_distance * cosd(angle));
                source_y = round(cols / 2 + source_distance * sind(angle));
        
                % Collect data from each detector
                for i = 1:num_detectors
                    detector_x = round(detector_positions(i));
                    detector_y = 1;  % You may need to adjust the vertical position based on your setup
        
                    % Simulate projection from source to detector
                    projection_line = app.bresenham_line([source_x, source_y], [detector_x, detector_y]);
        
                    % Accumulate readings along the projection line
                    scanner_readings(i, angle) = sum(rotated_phantom(sub2ind(size(rotated_phantom), projection_line(:, 1), projection_line(:, 2))));
                end
            end
        
            % Store the scanner readings in the structure
            scanner_data.readings = scanner_readings;
            scanner_data.detector_positions = detector_positions;
            scanner_data.rotation_angles = 1:rotation_step_angle:360;
        end

        % Helper function: Bresenham's line algorithm
        function line_points = bresenham_line(~, point1, point2)
            x1 = point1(1);
            y1 = point1(2);
            x2 = point2(1);
            y2 = point2(2);

            dx = abs(x2 - x1);
            dy = abs(y2 - y1);

            steep = dy > dx;

            if steep
                [x1, y1] = app.swap(x1, y1);
                [x2, y2] = app.swap(x2, y2);
            end

            if x1 > x2
                [x1, x2] = app.swap(x1, x2);
                [y1, y2] = app.swap(y1, y2);
            end

            dx = x2 - x1;
            dy = abs(y2 - y1);
            error = -dx / 2;
            ystep = -1;

            if y1 < y2
                ystep = 1;
            end

            y = y1:ystep:y2;

            line_points = zeros(length(x1:x2), 2);

            for i = 1:length(x1:x2)
                if steep
                    line_points(i, :) = [y(i), x1];
                else
                    line_points(i, :) = [x1, y(i)];
                end

                error = error + dy;

                if error > 0
                    y(i) = y(i) + ystep;
                    error = error - dx;
                end
            end
        end

        % Helper function: Swap values
        function [a, b] = swap(~, a, b)
            temp = a;
            a = b;
            b = temp;
        end

        
        %function results = func3(app)
         % In reconstruct_image method:
        function reconstructed_image = reconstruct_image(app, scanner_data)
            % Initialize the reconstructed image
            reconstructed_image = zeros(app.MatrixSize, app.MatrixSize);
            
            % Iterate over detectors and angles to accumulate readings
            for i = 1:length(scanner_data.detector_positions)
                for j = 1:length(scanner_data.rotation_angles)
                    % Calculate the position in the image based on the detector and angle
                    angle = scanner_data.rotation_angles(j);
        
                    % Calculate source position for each rotation angle
                    source_x = round(app.MatrixSize / 2 + app.source_distance * cosd(angle));
                    source_y = round(app.MatrixSize / 2 + app.source_distance * sind(angle));
        
                    detector_x = round(scanner_data.detector_positions(i));
                    detector_y = 1;  % Adjust this based on your setup
        
                    % Simulate projection from source to detector
                    projection_line = bresenham_line([source_x, source_y], [detector_x, detector_y]);
        
                    % Accumulate readings along the projection line
                    for line_point = projection_line'
                        reconstructed_image(line_point(1), line_point(2)) = reconstructed_image(line_point(1), line_point(2)) + scanner_data.readings(i, j);
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Set default values for MatrixSize and selectedButton
            app.MatrixSize = 700;  % Set your desired default matrix size
            app.selectedButton = 'Circular';  % Set your desired default phantom structure

        % Update the UI elements with the default values
            app.MatrixSizeEditField.Value = app.MatrixSize;
            app.PhantomStructureButtonGroup.SelectedObject = app.CircularButton;

        % Call the function to generate and display the default phantom
            app.ShowphatomButtonPushed();
        end

        % Value changed function: MatrixSizeEditField
        function MatrixSizeEditFieldValueChanged(app, event)
            app.MatrixSize = app.MatrixSizeEditField.Value;
            app.ShowphatomButtonPushed();  % Update the phantom plot directly
        end

        % Button pushed function: ShowphatomButton
        function ShowphatomButtonPushed(app, event)
            % Generate the phantom based on the selected structure and matrix size
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    app.generatedPhantom = zeros(app.MatrixSize);
        
                    % Define the properties of the circle
                    centerX = app.MatrixSize / 2;
                    centerY = app.MatrixSize / 2;
                    radius = app.MatrixSize / 4;
        
                    % Draw the circle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 1;
                            end
                        end
                    end

                case 'Rectangular'
                    % Create a blank image
                    app.generatedPhantom = zeros(app.MatrixSize);
        
                    % Define the properties of the rectangle
                    left = app.MatrixSize / 4;
                    right = 3 * app.MatrixSize / 4;
                    top = app.MatrixSize / 4;
                    bottom = 3 * app.MatrixSize / 4;
        
                    % Draw the rectangle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if x >= left && x <= right && y >= top && y <= bottom
                                app.generatedPhantom(x, y) = 1;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    app.generatedPhantom = phantom(app.MatrixSize);
            end
        
            % Display the phantom in the UIAxes
            imshow(app.generatedPhantom, 'Parent', app.UIAxes);
        end

        % Selection changed function: PhantomStructureButtonGroup
        function PhantomStructureButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.PhantomStructureButtonGroup.SelectedObject.Text;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        % This function will be called when the user clicks on the UIAxes.
        end

        % Value changed function: NumberofDetectorDropDown
        function NumberofDetectorDropDownValueChanged(app, event)
            %value = app.NumberofDetectorDropDown.Value;
            app.NumberofDetector = app.NumberofDetectorDropDown.Value;
            % Use the new value to update your program
            app.num_detectors = str2double(app.num_detectors);
        end

        % Drop down opening function: NumberofDetectorDropDown
        function NumberofDetectorDropDownOpening(app, event)

        end

        % Clicked callback: NumberofDetectorDropDown
        function NumberofDetectorDropDownClicked(app, event)
            item = event.InteractionInformation.Item;

        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            % Value changed function: RunButton
            if value == 1 % If the Run button is pressed
                % Get the parameters from the GUI
                num_detectors = str2double(app.NumberofDetector);
                detector_density = str2double(app.selectedDensity);
        
                % Get the selected item from DetectorArrayTypeDropDown
                items = app.DetectorArrayTypeDropDown.Items;
                selected_index = app.DetectorArrayTypeDropDown.Value;
        
                % Check if the index is valid
                if isempty(items) || ~isscalar(selected_index) || selected_index < 1 || selected_index > numel(items)
                    % Handle the error or provide a default value
                    disp('Invalid index or empty list.');
                    return;
                end
        
                % Get the selected detector array type
                detector_array_type = items{selected_index};
        
                source_distance = str2double(app.sourceDest);
                rotation_step_angle = str2double(app.rotationSlider);
        
                % Generate the phantom
                phantom = app.generate_phantom();
        
                % Operate the scanner
                scanner_data = app.operate_scanner(phantom, num_detectors, detector_density, detector_array_type, source_distance, rotation_step_angle);
        
                % Reconstruct the image
                reconstructed_image = app.reconstruct_image(scanner_data);
        
                % Display the reconstructed image
                imshow(reconstructed_image, 'Parent', app.UIAxes2);
            end
        end

        % Value changed function: DetectorDensityDropDown
        function DetectorDensityDropDownValueChanged(app, event)
            %value = app.DetectorDensityDropDown.Value;
            app.selectedDensity = app.DetectorDensityDropDown.Value;
            app.detector_density = str2double(app.selectedDensity);

        end

        % Drop down opening function: DetectorDensityDropDown
        function DetectorDensityDropDownOpening(app, event)
            
        end

        % Clicked callback: DetectorDensityDropDown
        function DetectorDensityDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Value changed function: SourceDistanceDropDown
        function SourceDistanceDropDownValueChanged(app, event)
            app.sourceDest = app.SourceDistanceDropDown.Value;
            app.source_distance = str2double(app.sourceDest);

        end

        % Value changed function: RotationStepAngleSlider
        function RotationStepAngleSliderValueChanged(app, event)
            app.rotationSlider = app.RotationStepAngleSlider.Value;
            app.rotation_step_angle = str2double(app.rotationSlider);
        end

        % Value changed function: DetectorArrayTypeDropDown
        function DetectorArrayTypeDropDownValueChanged(app, event)
            app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value;

            % Use the new value to update your program
            app.detector_array_type = num2str(app.arrTypeDropDown);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhantomGenerationTab = uitab(app.TabGroup);
            app.PhantomGenerationTab.Title = 'Phantom Generation';
            app.UIAxes = uiaxes(app.PhantomGenerationTab);
            title(app.UIAxes, 'Phantom')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.MatrixSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.MatrixSizeEditFieldLabel.Text = 'Matrix Size';
            app.PhantomStructureButtonGroup = uibuttongroup(app.PhantomGenerationTab);
            app.PhantomStructureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhantomStructureButtonGroupSelectionChanged, true);
            app.PhantomStructureButtonGroup.Title = 'Phantom Structure';
            app.CircularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.HeadPhantomButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.HeadPhantomButton.Text = 'Head Phantom';
            app.MatrixSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.MatrixSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixSizeEditFieldValueChanged, true);
            app.ShowphatomButton = uibutton(app.PhantomGenerationTab, 'push');
            app.ShowphatomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowphatomButtonPushed, true);
            app.ShowphatomButton.Text = 'Show phatom';
            app.ScannerControlsTab = uitab(app.TabGroup);
            app.ScannerControlsTab.Title = 'Scanner Controls';
            app.UIAxes2 = uiaxes(app.ScannerControlsTab);
            title(app.UIAxes2, ' Reconstructed Image')
            app.NumberofDetectorDropDownLabel = uilabel(app.ScannerControlsTab);
            app.NumberofDetectorDropDownLabel.Text = 'Number of Detector';
            app.NumberofDetectorDropDown = uidropdown(app.ScannerControlsTab);
            app.NumberofDetectorDropDown.Items = {'100', '200', '300'};
            app.NumberofDetectorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NumberofDetectorDropDownOpening, true);
            app.NumberofDetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofDetectorDropDownValueChanged, true);
            app.NumberofDetectorDropDown.ClickedFcn = createCallbackFcn(app, @NumberofDetectorDropDownClicked, true);
            app.NumberofDetectorDropDown.Value = '100';
            app.DetectorDensityDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorDensityDropDownLabel.Text = 'Detector Density';
            app.DetectorDensityDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorDensityDropDown.Items = {'0.5', '1.0', '1.5'};
            app.DetectorDensityDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DetectorDensityDropDownOpening, true);
            app.DetectorDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDensityDropDownValueChanged, true);
            app.DetectorDensityDropDown.ClickedFcn = createCallbackFcn(app, @DetectorDensityDropDownClicked, true);
            app.DetectorDensityDropDown.Value = '0.5';
            app.SourceDistanceDropDownLabel = uilabel(app.ScannerControlsTab);
            app.SourceDistanceDropDownLabel.Text = 'Source Distance';
            app.DetectorArrayTypeDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDownLabel.Text = 'Detector Array Type';
            app.DetectorArrayTypeDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDown.Items = {'Linear', 'Arc'};
            app.DetectorArrayTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorArrayTypeDropDownValueChanged, true);
            app.DetectorArrayTypeDropDown.Value = 'Linear';
            app.SourceDistanceDropDown = uidropdown(app.ScannerControlsTab);
            app.SourceDistanceDropDown.Items = {'100', '200', '300'};
            app.SourceDistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDistanceDropDownValueChanged, true);
            app.SourceDistanceDropDown.Value = '100';
            app.RunButton = uibutton(app.ScannerControlsTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RotationStepAngleSliderLabel = uilabel(app.ScannerControlsTab);
            app.RotationStepAngleSliderLabel.Text = 'Rotation Step Angle';
            app.RotationStepAngleSlider = uislider(app.ScannerControlsTab);
            app.RotationStepAngleSlider.Limits = [0 360];
            app.RotationStepAngleSlider.ValueChangedFcn = createCallbackFcn(app, @RotationStepAngleSliderValueChanged, true);
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.UIAxes3 = uiaxes(app.ImageAnalysisTab);
            title(app.UIAxes3, 'Image Analysis')
            app.TypeofImageAnalysisButtonGroup = uibuttongroup(app.ImageAnalysisTab);
            app.TypeofImageAnalysisButtonGroup.Title = 'Type of Image Analysis';
            app.SIandContrastButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIandContrastButton.Text = 'SI and Contrast';
            app.SIandContrastButton.Value = true;
            app.ImageDifferenceButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.ImageDifferenceButton.Text = 'Image Difference';
            app.SIProfilesButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIProfilesButton.Text = 'SI Profiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end