classdef WorkingArcImageReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PhantomGenerationTab            matlab.ui.container.Tab
        ShowphatomButton                matlab.ui.control.Button
        MatrixSizeEditField             matlab.ui.control.NumericEditField
        PhantomStructureButtonGroup     matlab.ui.container.ButtonGroup
        HeadPhantomButton               matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        CircularButton                  matlab.ui.control.RadioButton
        MatrixSizeEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ScannerControlsTab              matlab.ui.container.Tab
        RotationStepAngleSlider         matlab.ui.control.Slider
        RotationStepAngleSliderLabel    matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        SourceDistanceDropDown          matlab.ui.control.DropDown
        DetectorArrayTypeDropDownLabel  matlab.ui.control.Label
        DetectorArrayTypeDropDown       matlab.ui.control.DropDown
        SourceDistanceDropDownLabel     matlab.ui.control.Label
        DetectorDensityDropDown         matlab.ui.control.DropDown
        DetectorDensityDropDownLabel    matlab.ui.control.Label
        NumberofDetectorDropDown        matlab.ui.control.DropDown
        NumberofDetectorDropDownLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ImageAnalysisTab                matlab.ui.container.Tab
        TypeofImageAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        SIProfilesButton                matlab.ui.control.RadioButton
        ImageDifferenceButton           matlab.ui.control.RadioButton
        SIandContrastButton             matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MatrixSize
        selectedButton 
        generatedPhantom
        NumberofDetector
        arrTypeDropDown
        selectedDensity
        sourceDest
        rotationSlider
        %num_detectors
        %detector_density
        detector_array_type
        %source_distance
        %rotation_step_angle
    end
    
    methods (Access = private)
        
        function phantom_img = generate_phantom(app)
            disp('Reached phantom - Start');
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    phantom_img = zeros(app.MatrixSize);

                    % Define the properties of the circle
                    centerX = app.MatrixSize / 2;
                    centerY = app.MatrixSize / 2;
                    radius = app.MatrixSize / 4;

                    % Draw the circle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                phantom_img(x, y) = 1;
                            end
                        end
                    end

                case 'Rectangular'
                    % Create a blank image
                    phantom_img = zeros(app.MatrixSize);

                    % Define the properties of the rectangle
                    left = app.MatrixSize / 4;
                    right = 3 * app.MatrixSize / 4;
                    top = app.MatrixSize / 4;
                    bottom = 3 * app.MatrixSize / 4;

                    % Draw the rectangle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if x >= left && x <= right && y >= top && y <= bottom
                                phantom_img(x, y) = 1;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    phantom_img = phantom(app.MatrixSize);
            end
            disp('Reached phantom - End');

        end 
        
        % New method to operate the scanner
        function scanner_data = operate_scanner(app, phantom, num_detectors, detector_density, detector_array_type, source_distance, rotation_step_angle)
        disp('Reached operate_scanner - Start');
    
        % Initialize parameters
        rows = size(phantom, 1);
        cols = size(phantom, 2);
        rotation_angles = 0:rotation_step_angle:360 - rotation_step_angle;
        num_angles = length(rotation_angles);
    
        % Initialize scanner_readings matrix
        scanner_readings = zeros(num_detectors, num_angles);
    
        % Determine the positions of the detectors based on the array type
        if strcmp(detector_array_type, 'Linear')
            detector_positions = linspace(1, cols, num_detectors);
        elseif strcmp(detector_array_type, 'Arc')
            % For an arc, we need to calculate the positions differently
            % This requires more complex geometry calculations
            % Here's a simple example for illustration
            detector_positions = arc_positions(app, num_detectors, cols, source_distance);
        else
            error('Unknown detector array type');
        end

    % Iterate over each rotation angle
            for angleIdx = 1:num_angles
                % Rotate the phantom image
                rotated_phantom = imrotate(phantom, -rotation_angles(angleIdx), 'crop', 'bilinear');
        
                % Collect data for each detector position
                for detectorIdx = 1:num_detectors
                    % Calculate the projection for each detector
                    % This part needs to be adjusted based on your detector geometry
                    projection = sum(rotated_phantom(:, round(detector_positions(detectorIdx))));
                    scanner_readings(detectorIdx, angleIdx) = projection * detector_density;
                end
            end
        
            % Store the scanner readings in the structure
            scanner_data.readings = scanner_readings;
            scanner_data.rotation_angles = rotation_angles;
        
            disp('Reached operate_scanner - End');
        end

        function positions = arc_positions(app, num_detectors, image_width, radius)
            % This function calculates the coordinates of detectors positioned on an arc.
            % The arc is centered at the middle of the image width and has the specified radius.
        
            % Center of the arc (directly above the middle of the image width)
            x_center = image_width / 2;
            y_center = 0; % Adjust this if the center is not at the image edge
        
            % Define the angular span of the arc
            theta = linspace(-pi/4, pi/4, num_detectors); % Example angle range for the arc
    
            %max_angle = atan((image_width / 2) / radius);
            %angles = linspace(-max_angle, max_angle, num_detectors);


            % Initialize positions array
            positions = zeros(num_detectors, 2); % For storing x and y coordinates
        
            % Calculate x and y coordinates for each detector
            for i = 1:num_detectors
                positions(i, 1) = radius * cos(theta(i)) + x_center; % x-coordinate
                positions(i, 2) = radius * sin(theta(i)) + y_center; % y-coordinate
            end
        end


        % Helper function: Bresenham's line algorithm
        function line_points = bresenham_line(app, point1x, point1y, point2x, point2y)
            x1 = point1x;
            y1 = point1y;
            x2 = point2x;
            y2 = point2y;
        
            dx = abs(x2 - x1);
            dy = abs(y2 - y1);
        
            steep = dy > dx;
        
            if steep
                [x1, y1] = app.swap(x1, y1);
                [x2, y2] = app.swap(x2, y2);
            end
        
            if x1 > x2
                [x1, x2] = app.swap(x1, x2);
                [y1, y2] = app.swap(y1, y2);
            end
        
            dx = x2 - x1;
            dy = abs(y2 - y1);
            error = -dx / 2;
            ystep = (y1 < y2) * 2 - 1;
        
            % Initialize line_points array
            line_points = zeros(dx + 1, 2); % Assuming dx is the largest difference
            y = y1;
        
            for x = x1:x2
                if steep
                    line_points(x - x1 + 1, :) = [y, x];
                else
                    line_points(x - x1 + 1, :) = [x, y];
                end
        
                error = error + dy;
        
                if error > 0
                    y = y + ystep;
                    error = error - dx;
                end
            end
        end


        % Helper function: Swap values
        function [a, b] = swap(app, a, b)
            temp = a;
            a = b;
            b = temp;
        end

        
        %function results = func3(app)
         % In reconstruct_image method:
        function reconstructed_image = reconstruct_image(app, scanner_data)
            disp('Reached reconstruct_image - Start');
            
            % Extract projection data and angles for iradon
            projections = scanner_data.readings; % Assuming readings are the projections
            angles = scanner_data.rotation_angles;

            % Image reconstruction using iradon
            reconstructed_image = iradon(projections, angles);

            disp('Reached reconstruct_image - End');
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Set default values for MatrixSize and selectedButton
            app.MatrixSize = 700;  % Set your desired default matrix size
            app.selectedButton = 'Circular';  % Set your desired default phantom structure

        % Update the UI elements with the default values
            app.MatrixSizeEditField.Value = app.MatrixSize;
            app.PhantomStructureButtonGroup.SelectedObject = app.CircularButton;

        % Call the function to generate and display the default phantom
            app.ShowphatomButtonPushed();
        end

        % Value changed function: MatrixSizeEditField
        function MatrixSizeEditFieldValueChanged(app, event)
            app.MatrixSize = app.MatrixSizeEditField.Value;
            app.ShowphatomButtonPushed();  % Update the phantom plot directly
        end

        % Button pushed function: ShowphatomButton
        function ShowphatomButtonPushed(app, event)
            % Generate the phantom based on the selected structure and matrix size
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    app.generatedPhantom = zeros(app.MatrixSize);
        
                    % Define the properties of the circle
                    centerX = app.MatrixSize / 2;
                    centerY = app.MatrixSize / 2;
                    radius = app.MatrixSize / 4;
        
                    % Draw the circle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 1;
                            end
                        end
                    end

                case 'Rectangular'
                    % Create a blank image
                    app.generatedPhantom = zeros(app.MatrixSize);
        
                    % Define the properties of the rectangle
                    left = app.MatrixSize / 4;
                    right = 3 * app.MatrixSize / 4;
                    top = app.MatrixSize / 4;
                    bottom = 3 * app.MatrixSize / 4;
        
                    % Draw the rectangle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if x >= left && x <= right && y >= top && y <= bottom
                                app.generatedPhantom(x, y) = 1;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    app.generatedPhantom = phantom(app.MatrixSize);
            end
        
            % Display the phantom in the UIAxes
            imshow(app.generatedPhantom, 'Parent', app.UIAxes);
        end

        % Selection changed function: PhantomStructureButtonGroup
        function PhantomStructureButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.PhantomStructureButtonGroup.SelectedObject.Text;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        % This function will be called when the user clicks on the UIAxes.
        end

        % Value changed function: NumberofDetectorDropDown
        function NumberofDetectorDropDownValueChanged(app, event)
            %value = app.NumberofDetectorDropDown.Value;
            app.NumberofDetector = str2double(app.NumberofDetectorDropDown.Value);
            % Use the new value to update your program
            %app.NumberofDetector = str2double(app.num_detectors);
            %disp(['Selected Num of Detector: ' num2str(app.NumberofDetector)]);

        end

        % Drop down opening function: NumberofDetectorDropDown
        function NumberofDetectorDropDownOpening(app, event)

        end

        % Clicked callback: NumberofDetectorDropDown
        function NumberofDetectorDropDownClicked(app, event)
            %item = event.InteractionInformation.Item;
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            if value == 1 % If the Run button is pressed
                % Retrieve and validate the parameters
                app.NumberofDetector = str2double(app.NumberofDetectorDropDown.Value);
                app.selectedDensity = str2double(app.DetectorDensityDropDown.Value);
                app.sourceDest = str2double(app.SourceDistanceDropDown.Value);
                app.rotationSlider = app.RotationStepAngleSlider.Value; % Already a numeric value
                app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value; % String value
        
                % Check for valid number for Number of Detector, Source Distance, and Detector Density
                if isnan(app.NumberofDetector) || isnan(app.selectedDensity) || isnan(app.sourceDest)
                    disp('Invalid input for number of detectors, source distance, or detector density. Please select valid numbers.');
                    return;
                end
        
                % Display the selected parameters
                disp(['Selected Num of Detector: ' num2str(app.NumberofDetector)]);
                disp(['Selected Detector Density: ' num2str(app.selectedDensity)]);
                disp(['Selected Source Distance: ' num2str(app.sourceDest)]);
                disp(['Selected Rotation Step Angle: ' num2str(app.rotationSlider)]);
                disp(['Selected Detector Array Type: ' app.arrTypeDropDown]);
        
                % Generate the phantom
                phantom = app.generate_phantom(); % Assuming you have a method to generate the phantom
        
                % Operate the scanner
                % This will use the retrieved values for scanning
                scanner_data = app.operate_scanner(phantom, app.NumberofDetector, app.selectedDensity, app.arrTypeDropDown, app.sourceDest, app.rotationSlider);
        
                % Reconstruct the image
                reconstructed_image = app.reconstruct_image(scanner_data);
        
                % Display the reconstructed image
                imshow(reconstructed_image, 'Parent', app.UIAxes2);
            end
        end

        % Value changed function: DetectorDensityDropDown
        function DetectorDensityDropDownValueChanged(app, event)
            %value = app.DetectorDensityDropDown.Value;
            app.selectedDensity = str2double(app.DetectorDensityDropDown.Value);
            %app.detector_density = str2double(app.selectedDensity);
            %disp(['Selected Detector Dest: ' num2str(app.selectedDensity)]);

        end

        % Drop down opening function: DetectorDensityDropDown
        function DetectorDensityDropDownOpening(app, event)
            
        end

        % Clicked callback: DetectorDensityDropDown
        function DetectorDensityDropDownClicked(app, event)
            %item = event.InteractionInformation.Item;
            
        end

        % Value changed function: SourceDistanceDropDown
        function SourceDistanceDropDownValueChanged(app, event)
            app.sourceDest = str2double(app.SourceDistanceDropDown.Value);
            %app.source_distance = str2double(app.sourceDest);
            %disp(['Selected Source Dist: ' num2str(app.sourceDest)]);

        end

        % Value changed function: RotationStepAngleSlider
        function RotationStepAngleSliderValueChanged(app, event)
            app.rotationSlider = app.RotationStepAngleSlider.Value;
            %app.rotationSlider = str2double(app.rotationSlider);
            %disp(['Selected Rotation Step Angle: ' num2str(app.rotationSlider)]);

        end

        % Value changed function: DetectorArrayTypeDropDown
        function DetectorArrayTypeDropDownValueChanged(app, event)
            app.arrTypeDropDown = app.DetectorArrayTypeDropDown.Value;

            % Use the new value to update your program
            app.detector_array_type = app.arrTypeDropDown;
            %disp(['Selected Arr Type: ' (app.arrTypeDropDown)]);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhantomGenerationTab = uitab(app.TabGroup);
            app.PhantomGenerationTab.Title = 'Phantom Generation';
            app.UIAxes = uiaxes(app.PhantomGenerationTab);
            title(app.UIAxes, 'Phantom')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.MatrixSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.MatrixSizeEditFieldLabel.Text = 'Matrix Size';
            app.PhantomStructureButtonGroup = uibuttongroup(app.PhantomGenerationTab);
            app.PhantomStructureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhantomStructureButtonGroupSelectionChanged, true);
            app.PhantomStructureButtonGroup.Title = 'Phantom Structure';
            app.CircularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.HeadPhantomButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.HeadPhantomButton.Text = 'Head Phantom';
            app.MatrixSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.MatrixSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixSizeEditFieldValueChanged, true);
            app.ShowphatomButton = uibutton(app.PhantomGenerationTab, 'push');
            app.ShowphatomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowphatomButtonPushed, true);
            app.ShowphatomButton.Text = 'Show phatom';
            app.ScannerControlsTab = uitab(app.TabGroup);
            app.ScannerControlsTab.Title = 'Scanner Controls';
            app.UIAxes2 = uiaxes(app.ScannerControlsTab);
            title(app.UIAxes2, ' Reconstructed Image')
            app.NumberofDetectorDropDownLabel = uilabel(app.ScannerControlsTab);
            app.NumberofDetectorDropDownLabel.Text = 'Number of Detector';
            app.NumberofDetectorDropDown = uidropdown(app.ScannerControlsTab);
            app.NumberofDetectorDropDown.Items = {'50', '100', '200', '300', '400', '500', '750', '1000', '1250', '1500', '2000'};
            app.NumberofDetectorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NumberofDetectorDropDownOpening, true);
            app.NumberofDetectorDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofDetectorDropDownValueChanged, true);
            app.NumberofDetectorDropDown.ClickedFcn = createCallbackFcn(app, @NumberofDetectorDropDownClicked, true);
            app.NumberofDetectorDropDown.Value = '100';
            app.DetectorDensityDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorDensityDropDownLabel.Text = 'Detector Density';
            app.DetectorDensityDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorDensityDropDown.Items = {'0', '0.1', '0.5', '0.6', '0.7', '0.8', '0.9', '1.0', '1.5', '2.0', '3.0', '4.0', '5.0'};
            app.DetectorDensityDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DetectorDensityDropDownOpening, true);
            app.DetectorDensityDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorDensityDropDownValueChanged, true);
            app.DetectorDensityDropDown.ClickedFcn = createCallbackFcn(app, @DetectorDensityDropDownClicked, true);
            app.DetectorDensityDropDown.Value = '0';
            app.SourceDistanceDropDownLabel = uilabel(app.ScannerControlsTab);
            app.SourceDistanceDropDownLabel.Text = 'Source Distance';
            app.DetectorArrayTypeDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDown.Items = {'Linear', 'Arc'};
            app.DetectorArrayTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorArrayTypeDropDownValueChanged, true);
            app.DetectorArrayTypeDropDown.Value = 'Linear';
            app.DetectorArrayTypeDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDownLabel.Text = 'Detector Array Type';
            app.SourceDistanceDropDown = uidropdown(app.ScannerControlsTab);
            app.SourceDistanceDropDown.Items = {'100', '150', '200', '250', '300', '400', '500'};
            app.SourceDistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDistanceDropDownValueChanged, true);
            app.SourceDistanceDropDown.Value = '100';
            app.RunButton = uibutton(app.ScannerControlsTab, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RotationStepAngleSliderLabel = uilabel(app.ScannerControlsTab);
            app.RotationStepAngleSliderLabel.Text = 'Rotation Step Angle';
            app.RotationStepAngleSlider = uislider(app.ScannerControlsTab);
            app.RotationStepAngleSlider.Limits = [1 10];
            app.RotationStepAngleSlider.ValueChangedFcn = createCallbackFcn(app, @RotationStepAngleSliderValueChanged, true);
            app.RotationStepAngleSlider.Value = 1;
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.UIAxes3 = uiaxes(app.ImageAnalysisTab);
            title(app.UIAxes3, 'Image Analysis')
            app.TypeofImageAnalysisButtonGroup = uibuttongroup(app.ImageAnalysisTab);
            app.TypeofImageAnalysisButtonGroup.Title = 'Type of Image Analysis';
            app.SIandContrastButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIandContrastButton.Text = 'SI and Contrast';
            app.SIandContrastButton.Value = true;
            app.ImageDifferenceButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.ImageDifferenceButton.Text = 'Image Difference';
            app.SIProfilesButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIProfilesButton.Text = 'SI Profiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkingArcImageReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end