classdef testapp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PhantomGenerationTab            matlab.ui.container.Tab
        ShowphatomButton                matlab.ui.control.Button
        MatrixSizeEditField             matlab.ui.control.NumericEditField
        PhantomStructureButtonGroup     matlab.ui.container.ButtonGroup
        HeadPhantomButton               matlab.ui.control.RadioButton
        RectangularButton               matlab.ui.control.RadioButton
        CircularButton                  matlab.ui.control.RadioButton
        MatrixSizeEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ScannerControlsTab              matlab.ui.container.Tab
        RotationStepAngleSlider         matlab.ui.control.Slider
        RotationStepAngleSliderLabel    matlab.ui.control.Label
        RunButton                       matlab.ui.control.StateButton
        SourceDistanceDropDown          matlab.ui.control.DropDown
        DetectorArrayTypeDropDown       matlab.ui.control.DropDown
        DetectorArrayTypeDropDownLabel  matlab.ui.control.Label
        SourceDistanceDropDownLabel     matlab.ui.control.Label
        DetectorDensityDropDown         matlab.ui.control.DropDown
        DetectorDensityDropDownLabel    matlab.ui.control.Label
        NumberofDetectorDropDown        matlab.ui.control.DropDown
        NumberofDetectorDropDownLabel   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        ImageAnalysisTab                matlab.ui.container.Tab
        TypeofImageAnalysisButtonGroup  matlab.ui.container.ButtonGroup
        SIProfilesButton                matlab.ui.control.RadioButton
        ImageDifferenceButton           matlab.ui.control.RadioButton
        SIandContrastButton             matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MatrixSize
        selectedButton 
        generatedPhantom
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Set default values for MatrixSize and selectedButton
            app.MatrixSize = 700;  % Set your desired default matrix size
            app.selectedButton = 'Circular';  % Set your desired default phantom structure

        % Update the UI elements with the default values
            app.MatrixSizeEditField.Value = app.MatrixSize;
            app.PhantomStructureButtonGroup.SelectedObject = app.CircularButton;

        % Call the function to generate and display the default phantom
            app.ShowphatomButtonPushed();
        end

        % Value changed function: MatrixSizeEditField
        function MatrixSizeEditFieldValueChanged(app, event)
            app.MatrixSize = app.MatrixSizeEditField.Value;
            app.ShowphatomButtonPushed();  % Update the phantom plot directly
        end

        % Button pushed function: ShowphatomButton
        function ShowphatomButtonPushed(app, event)
            % Generate the phantom based on the selected structure and matrix size
            switch app.selectedButton
                case 'Circular'
                    % Create a blank image
                    app.generatedPhantom = zeros(app.MatrixSize);
        
                    % Define the properties of the circle
                    centerX = app.MatrixSize / 2;
                    centerY = app.MatrixSize / 2;
                    radius = app.MatrixSize / 4;
        
                    % Draw the circle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if (x - centerX)^2 + (y - centerY)^2 <= radius^2
                                app.generatedPhantom(x, y) = 1;
                            end
                        end
                    end

                case 'Rectangular'
                    % Create a blank image
                    app.generatedPhantom = zeros(app.MatrixSize);
        
                    % Define the properties of the rectangle
                    left = app.MatrixSize / 4;
                    right = 3 * app.MatrixSize / 4;
                    top = app.MatrixSize / 4;
                    bottom = 3 * app.MatrixSize / 4;
        
                    % Draw the rectangle in the image
                    for x = 1:app.MatrixSize
                        for y = 1:app.MatrixSize
                            if x >= left && x <= right && y >= top && y <= bottom
                                app.generatedPhantom(x, y) = 1;
                            end
                        end
                    end

                case 'Head Phantom'
                    % Use MATLAB's built-in phantom function to generate a head phantom
                    app.generatedPhantom = phantom(app.MatrixSize);
            end
        
            % Display the phantom in the UIAxes
            imshow(app.generatedPhantom, 'Parent', app.UIAxes);
        end

        % Selection changed function: PhantomStructureButtonGroup
        function PhantomStructureButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.PhantomStructureButtonGroup.SelectedObject.Text;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        % This function will be called when the user clicks on the UIAxes.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PhantomGenerationTab = uitab(app.TabGroup);
            app.PhantomGenerationTab.Title = 'Phantom Generation';
            app.UIAxes = uiaxes(app.PhantomGenerationTab);
            title(app.UIAxes, 'Phantom')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.MatrixSizeEditFieldLabel = uilabel(app.PhantomGenerationTab);
            app.MatrixSizeEditFieldLabel.Text = 'Matrix Size';
            app.PhantomStructureButtonGroup = uibuttongroup(app.PhantomGenerationTab);
            app.PhantomStructureButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PhantomStructureButtonGroupSelectionChanged, true);
            app.PhantomStructureButtonGroup.Title = 'Phantom Structure';
            app.CircularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangularButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.HeadPhantomButton = uiradiobutton(app.PhantomStructureButtonGroup);
            app.HeadPhantomButton.Text = 'Head Phantom';
            app.MatrixSizeEditField = uieditfield(app.PhantomGenerationTab, 'numeric');
            app.MatrixSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MatrixSizeEditFieldValueChanged, true);
            app.ShowphatomButton = uibutton(app.PhantomGenerationTab, 'push');
            app.ShowphatomButton.ButtonPushedFcn = createCallbackFcn(app, @ShowphatomButtonPushed, true);
            app.ShowphatomButton.Text = 'Show phatom';
            app.ScannerControlsTab = uitab(app.TabGroup);
            app.ScannerControlsTab.Title = 'Scanner Controls';
            app.UIAxes2 = uiaxes(app.ScannerControlsTab);
            title(app.UIAxes2, ' Reconstructed Image')
            app.NumberofDetectorDropDownLabel = uilabel(app.ScannerControlsTab);
            app.NumberofDetectorDropDownLabel.Text = 'Number of Detector';
            app.NumberofDetectorDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorDensityDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorDensityDropDownLabel.Text = 'Detector Density';
            app.DetectorDensityDropDown = uidropdown(app.ScannerControlsTab);
            app.SourceDistanceDropDownLabel = uilabel(app.ScannerControlsTab);
            app.SourceDistanceDropDownLabel.Text = 'Source Distance';
            app.DetectorArrayTypeDropDownLabel = uilabel(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDownLabel.Text = 'Detector Array Type';
            app.DetectorArrayTypeDropDown = uidropdown(app.ScannerControlsTab);
            app.DetectorArrayTypeDropDown.Items = {'Linear', 'Arc'};
            app.DetectorArrayTypeDropDown.Value = 'Linear';
            app.SourceDistanceDropDown = uidropdown(app.ScannerControlsTab);
            app.RunButton = uibutton(app.ScannerControlsTab, 'state');
            app.RunButton.Text = 'Run';
            app.RotationStepAngleSliderLabel = uilabel(app.ScannerControlsTab);
            app.RotationStepAngleSliderLabel.Text = 'Rotation Step Angle';
            app.RotationStepAngleSlider = uislider(app.ScannerControlsTab);
            app.RotationStepAngleSlider.Limits = [0 360];
            app.ImageAnalysisTab = uitab(app.TabGroup);
            app.ImageAnalysisTab.Title = 'Image Analysis';
            app.UIAxes3 = uiaxes(app.ImageAnalysisTab);
            title(app.UIAxes3, 'Image Analysis')
            app.TypeofImageAnalysisButtonGroup = uibuttongroup(app.ImageAnalysisTab);
            app.TypeofImageAnalysisButtonGroup.Title = 'Type of Image Analysis';
            app.SIandContrastButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIandContrastButton.Text = 'SI and Contrast';
            app.SIandContrastButton.Value = true;
            app.ImageDifferenceButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.ImageDifferenceButton.Text = 'Image Difference';
            app.SIProfilesButton = uiradiobutton(app.TypeofImageAnalysisButtonGroup);
            app.SIProfilesButton.Text = 'SI Profiles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end